classdef dataViz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        SliderLabel              matlab.ui.control.Label
        Slider                   matlab.ui.control.Slider
        StimShapeButtonGroup     matlab.ui.container.ButtonGroup
        SineButton               matlab.ui.control.RadioButton
        StepButton               matlab.ui.control.RadioButton
        FrequencyDropDownLabel   matlab.ui.control.Label
        FrequencyDropDown        matlab.ui.control.DropDown
        StimTypeDropDownLabel    matlab.ui.control.Label
        StimTypeDropDown         matlab.ui.control.DropDown
        ListBox                  matlab.ui.control.ListBox
        CyclesAfterSpinnerLabel  matlab.ui.control.Label
        CyclesAfterSpinner       matlab.ui.control.Spinner
        GenerateTimelapseButton  matlab.ui.control.Button
        AmplitudeDropDownLabel   matlab.ui.control.Label
        AmplitudeDropDown        matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        dataTable; % Description
        dataDirectory;
        excelLoc;
        
    end
    
    methods (Access = public)
        
        function UpdateFilterBox(app)
            
            tempTable = app.dataTable;
            
            % Amplitude Filter
            if isa(app.AmplitudeDropDown.Value, 'double')
                tempTable(tempTable.Amplitude ~= str2double(app.AmplitudeDropDown.Value), :) = [];
            else
                tempTable(~strcmp(tempTable.Amplitude, app.AmplitudeDropDown.Value), :) = [];
            end
            
            % StimType Filter 
            if isa(app.AmplitudeDropDown.Value, 'double')
                tempTable(tempTable.StimType ~= str2double(app.StimTypeDropDown.Value), :) = [];
            else
                tempTable(~strcmp(tempTable.StimType, app.StimTypeDropDown.Value), :) = [];
            end
            
            % Freq_Duration Filter
            if isa(app.FrequencyDropDown.Value, 'double')
                tempTable(tempTable.Freq_Duration ~= str2double(app.FrequencyDropDown.Value), :) = [];
            else
                tempTable(~strcmp(strsplit(num2str(tempTable.Freq_Duration')), app.FrequencyDropDown.Value), :) = [];
            end

            % Put Cell Names into box
            app.ListBox.Items = tempTable.Filename;
        end
    
        function plotThings(app)
            cla(app.UIAxes);
            hold(app.UIAxes);
            
            for i = 1:length(app.ListBox.Value)
                
                % Get Excel Table Information
                fileName = app.ListBox.Value{i};
                tableRow = app.dataTable(strcmp(app.dataTable.Filename, fileName), :);
                
                % Find behavior
                bfileData = dir([app.dataDirectory '\**\*' fileName]);
                bPath = fullfile(bfileData.folder, bfileData.name);
                chanDat = cx2dat(bPath, 6, 500);
                
                % Find Ephys  
                if ~strcmp(tableRow.EphysFilename, 'None')
                    eSamplerate = 50000;
                    
                    efileData = dir([app.dataDirectory '\**\*' tableRow.EphysFilename{:}]);
                    ePath = fullfile(efileData.folder, efileData.name);
                    ephysDat = maestro2dat(ePath, eSamplerate);
                    
                    % Are you missing an alignment value?
                    if tableRow.EphysAlignmentValue == 0 || isnan(tableRow.EphysAlignmentValue)
                        [chanDat, shiftAmt, ~] = alignCXandMaestro(chanDat, ephysDat, 1);
                        app.dataTable(strcmp(app.dataTable.Filename, fileName), :).EphysAlignmentValue = shiftAmt;
                        writetable(app.dataTable, app.excelLoc);
                    else
                        timeEphys = 0:1/eSamplerate:(length(ephysDat.data)-1)/eSamplerate;
                        timeEphys = timeEphys + tableRow.EphysAlignmentValue;
                        ephysDat.tstart = timeEphys(1);
                        ephysDat.tend = timeEphys(end);
                        chanDat(end+1) = ephysDat;
                    end
                    
                end
                
                %% Plot
                
                % Plot hhvel -1
                cycleLength = chanDat(5).samplerate / str2double(app.FrequencyDropDown.Value);
                timeVec = 0:( 1/chanDat(5).samplerate ):(cycleLength-1)/chanDat(5).samplerate;
                [hhvelMat, hhvelMean] = VOR_breakTrace(cycleLength, 1, chanDat(5).data);
                plot(app.UIAxes, timeVec, hhvelMean', 'k'); 
                
                % Plot htvel = 7
                cycleLength = chanDat(7).samplerate / str2double(app.FrequencyDropDown.Value);
                timeVec = 0:( 1/chanDat(7).samplerate ):(cycleLength-1)/chanDat(7).samplerate;
                [htvelMat, htvelMean] = VOR_breakTrace(cycleLength, 1, chanDat(7).data);
                plot(app.UIAxes, timeVec, htvelMean', 'b');
                
                % Plot hevel - 3
                cycleLength = chanDat(3).samplerate / str2double(app.FrequencyDropDown.Value);
                timeVec = 0:( 1/chanDat(3).samplerate ):(cycleLength-1)/chanDat(3).samplerate;
                [hevelMat, hevelMean] = VOR_breakTrace(cycleLength, 1, chanDat(3).data);
                plot(app.UIAxes, timeVec, hevelMat', 'r');


                % Plot Ephys
                if ~strcmp(tableRow.EphysFilename, 'None')
                    
                    timeofsimplespikes = datchandata(chanDat,'ss');
                    timeVec = 0:( 1/eSamplerate):(length(datchandata(chanDat, 'Ephys'))-1)/eSamplerate;
                    cycleLength = eSamplerate / str2double(app.FrequencyDropDown.Value);
                    t_samp = 1/eSamplerate;
                    t_samp = 1/eSamplerate;
                    tt = [0:t_samp:timeVec(end)];
                   
                    ifr = instantfr(timeofsimplespikes, tt);
                    [ifrMat, ifrMean] = VOR_breakTrace(cycleLength, 1, ifr);
                    timeVec = 0:( 1/eSamplerate):(cycleLength-1)/eSamplerate;
                    plot(app.UIAxes, timeVec, ifrMean'/10, 'c');

                end
            end
            legend(app.UIAxes,{'Head Vel','Target Vel', 'Eye Vel','ss'})
            xlim(app.UIAxes, [0 max(timeVec)]);
            ylim(app.UIAxes, [-50 50]);
            hold(app.UIAxes);
            
        end
             
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load Excel file database/table
            app.excelLoc = 'C:\Users\Public\RaymondLabCode\Projects\Max - CF Project\Monkey Metadata by Max.xlsx';
            app.dataTable = readtable(app.excelLoc);
            app.dataDirectory = 'G:\My Drive\Expmt Data\2019_05 - Max Climbing Fiber\Initial Data for testing';
            
            % populate options windows with what exists in the excel file
            uniqueStimType = unique(app.dataTable.StimType);
            uniqueStimType = uniqueStimType(~cellfun('isempty',uniqueStimType));
            app.StimTypeDropDown.Items = uniqueStimType;
            
            uniqueAmplitudes = unique(app.dataTable.Amplitude);
            %uniqueAmplitudes(isnan(uniqueAmplitudes)) = []
            %uniqueAmplitudes = strsplit(num2str(uniqueAmplitudes'))';
            uniqueAmplitudes = uniqueAmplitudes(~cellfun('isempty', uniqueAmplitudes));
            app.AmplitudeDropDown.Items = uniqueAmplitudes;
            
            uniqueFrequency = unique(app.dataTable.Freq_Duration);
            uniqueFrequency(isnan(uniqueFrequency)) = [];
            %uniqueFrequency = uniqueFrequency(~cellfun('isempty', uniqueFrequency));
            uniqueFrequency = strsplit(num2str(uniqueFrequency'));
            app.FrequencyDropDown.Items = uniqueFrequency;
            
        end

        % Button pushed function: GenerateTimelapseButton
        function GenerateTimelapseButtonPushed(app, event)
            plotThings(app)
        end

        % Value changed function: StimTypeDropDown
        function StimTypeDropDownValueChanged(app, event)
            UpdateFilterBox(app)
        end

        % Value changed function: FrequencyDropDown
        function FrequencyDropDownValueChanged(app, event)
            UpdateFilterBox(app)
        end

        % Value changed function: AmplitudeDropDown
        function AmplitudeDropDownValueChanged(app, event)
            UpdateFilterBox(app)
        end

        % Selection changed function: StimShapeButtonGroup
        function StimShapeButtonGroupSelectionChanged(app, event)
            UpdateFilterBox(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.StimShapeButtonGroup = uibuttongroup(app.UIFigure);
            app.StimShapeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StimShapeButtonGroupSelectionChanged, true);
            app.StimShapeButtonGroup.Title = 'Stim Shape';
            app.SineButton = uiradiobutton(app.StimShapeButtonGroup);
            app.SineButton.Text = 'Sine';
            app.SineButton.Value = true;
            app.StepButton = uiradiobutton(app.StimShapeButtonGroup);
            app.StepButton.Text = 'Step';
            app.FrequencyDropDownLabel = uilabel(app.UIFigure);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDropDown = uidropdown(app.UIFigure);
            app.FrequencyDropDown.Items = {'.6Hz', '1 Hz', '2 Hz'};
            app.FrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencyDropDownValueChanged, true);
            app.FrequencyDropDown.Value = '2 Hz';
            app.StimTypeDropDownLabel = uilabel(app.UIFigure);
            app.StimTypeDropDownLabel.Text = 'Stim Type';
            app.StimTypeDropDown = uidropdown(app.UIFigure);
            app.StimTypeDropDown.Items = {'Dark', 'x1', 'x0', 'x2', 'VORC', 'Pos Steps', 'H Pursuit', 'V Pursuit'};
            app.StimTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StimTypeDropDownValueChanged, true);
            app.StimTypeDropDown.Value = 'x0';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Pears', 'Apples', ''};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'Pears'};
            app.CyclesAfterSpinnerLabel = uilabel(app.UIFigure);
            app.CyclesAfterSpinnerLabel.Text = 'Cycles After';
            app.CyclesAfterSpinner = uispinner(app.UIFigure);
            app.CyclesAfterSpinner.Value = 1;
            app.GenerateTimelapseButton = uibutton(app.UIFigure, 'push');
            app.GenerateTimelapseButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTimelapseButtonPushed, true);
            app.GenerateTimelapseButton.Text = 'Generate Timelapse';
            app.AmplitudeDropDownLabel = uilabel(app.UIFigure);
            app.AmplitudeDropDownLabel.Text = 'Amplitude';
            app.AmplitudeDropDown = uidropdown(app.UIFigure);
            app.AmplitudeDropDown.Items = {'Any', '10', '15', '20', '25', '30'};
            app.AmplitudeDropDown.ValueChangedFcn = createCallbackFcn(app, @AmplitudeDropDownValueChanged, true);
            app.AmplitudeDropDown.Value = '10';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dataViz

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end